classdef Control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        BATCONTROLTab                 matlab.ui.container.Tab
        ROLLCONTROLOFFButton          matlab.ui.control.Button
        ROLLCONTROLONButton           matlab.ui.control.Button
        ROLLGauge                     matlab.ui.control.SemicircularGauge
        ROLLGaugeLabel                matlab.ui.control.Label
        PITCHGauge                    matlab.ui.control.LinearGauge
        PITCHGaugeLabel               matlab.ui.control.Label
        DIRECTIONLabel                matlab.ui.control.Label
        RIGHTLabel                    matlab.ui.control.Label
        LEFTLabel                     matlab.ui.control.Label
        RESETButton                   matlab.ui.control.Button
        MANUAL_Slider                 matlab.ui.control.Slider
        START_FLAPPING                matlab.ui.control.Button
        FREQUENCYSlider               matlab.ui.control.Slider
        FREQUENCYSliderLabel          matlab.ui.control.Label
        BATSETTINGSTab                matlab.ui.container.Tab
        ANTAGONISTPanel               matlab.ui.container.Panel
        APPLY_R_B                     matlab.ui.control.Button
        APPLY_L_B                     matlab.ui.control.Button
        DUTYCYCLE_B_Slider            matlab.ui.control.Slider
        DUTYCYCLE25Label_4            matlab.ui.control.Label
        POWER_RB_Slider               matlab.ui.control.Slider
        POWERLEFTWLabel_2             matlab.ui.control.Label
        APPLY_B                       matlab.ui.control.Button
        POWER_LB_Slider               matlab.ui.control.Slider
        POWERLEFTWLabel               matlab.ui.control.Label
        AGONISTPanel                  matlab.ui.container.Panel
        APPLY_R_A                     matlab.ui.control.Button
        APPLY_L_A                     matlab.ui.control.Button
        APPLY_A                       matlab.ui.control.Button
        DUTYCYCLE_A_Slider            matlab.ui.control.Slider
        DUTYCYCLE25Label_3            matlab.ui.control.Label
        POWER_RA_Slider               matlab.ui.control.Slider
        POWERRIGHT200255Label         matlab.ui.control.Label
        POWER_LA_Slider               matlab.ui.control.Slider
        POWERRIGHTWLabel              matlab.ui.control.Label
        FORCESENSORTab                matlab.ui.container.Tab
        STARTREADINGButton            matlab.ui.control.Button
        TempoSpinner                  matlab.ui.control.Spinner
        TempoSpinnerLabel             matlab.ui.control.Label
        dgLabel                       matlab.ui.control.Label
        OffsetEditField               matlab.ui.control.NumericEditField
        OffsetEditFieldLabel          matlab.ui.control.Label
        TareButton                    matlab.ui.control.Button
        ConnectSERIALLoadCell_Button  matlab.ui.control.Button
        SerialPort_LoadCell           matlab.ui.control.DropDown
        SerialPortDropDownLabel       matlab.ui.control.Label
        CONNECTBTLoadCell_Button      matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        CONNECT_Button                matlab.ui.control.Button
    end

    
    properties (Access = public)
        batmav;
        loadcell;
        cmdID;
        cmdVALUE;
        cmdMANUAL;
        sensorPITCH;
        sensorROLL;
        offset;

        bat_connected = false;
        lc_connected = false;
        lcSerial_connected = false;
        flapping = false;
    end
    
    properties (Access = private)
        SensorTimer
    end
    
    methods (Access = private)
        
        function SensorUpdate(app,src,event)
            if ~app.batmav.Connected
                app.bat_connected = false;
                app.CONNECT_Button.Text = "CONNECT TO BAT";
                app.CONNECT_Button.FontColor = [0,0,0];
            else
                app.bat_connected = true;
            end

             if(app.bat_connected == true)
                 app.ROLLGauge.Value = read(app.sensorROLL) - 90;
                 app.PITCHGauge.Value = read(app.sensorPITCH) - 90;
             end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SensorTimer = timer("ExecutionMode","fixedRate","Period",0.5,"TimerFcn",@app.SensorUpdate);
            app.SerialPort_LoadCell.Items = serialportlist("available");
        end

        % Button pushed function: CONNECT_Button
        function CONNECT_ButtonPushed(app, event)
             if(app.bat_connected == false)
                app.batmav = ble("BATMAV");
                app.cmdID = characteristic(app.batmav,"4fafc201-1fb5-459e-8fcc-c5c9c331914b","beb5483e-36e1-4688-b7f5-ea07361b26a8");
                app.cmdVALUE = characteristic(app.batmav,"4fafc201-1fb5-459e-8fcc-c5c9c331914b","6e1e74e1-435d-4c95-8dff-9e9651b77bfe");
                app.cmdMANUAL = characteristic(app.batmav,"4fafc201-1fb5-459e-8fcc-c5c9c331914b","a16512fd-ce94-4245-8a6f-c97fa5c6fdeb");
                app.sensorPITCH = characteristic(app.batmav,"4fafc201-1fb5-459e-8fcc-c5c9c331914b","7a66f003-e2c3-4189-95b7-34608d313145");
                app.sensorROLL = characteristic(app.batmav,"4fafc201-1fb5-459e-8fcc-c5c9c331914b","7f0c9e07-ef47-4590-b419-6df5fe9bf65a");
                app.bat_connected = true;
                app.CONNECT_Button.Text = "BAT CONNECTED";
                app.CONNECT_Button.FontColor = [0.47,0.67,0.19];
                if strcmp(app.SensorTimer.Running,"off")
                    start(app.SensorTimer);
                end
             end
        end

        % Button pushed function: CONNECTBTLoadCell_Button
        function CONNECTBTLoadCell_ButtonPushed(app, event)
            app.loadcell = bluetooth('BatMAV-LoadCell',1);
            app.lc_connected = true;
            app.CONNECTBTLoadCell_Button.Text = "Connected";
            app.CONNECTBTLoadCell_Button.FontColor = "Green";
        end

        % Button pushed function: ConnectSERIALLoadCell_Button
        function ConnectSERIALLoadCell_ButtonPushed(app, event)
             if(app.lcSerial_connected == false)
                app.loadcell = serialport(app.SerialPort_LoadCell.Value, 115200);
                fopen(app.loadcell);
                app.ConnectSERIALLoadCell_Button.Text = "Connected";
                app.ConnectSERIALLoadCell_Button.FontColor = "Green";
                app.lcSerial_connected = true;
            else
                fclose(app.loadcell);
                app.ConnectSERIALLoadCell_Button.Text = "Connect to Serial";
                app.ConnectSERIALLoadCell_Button.FontColor = "Black";
                app.lcSerial_connected = false;
            end
        end

        % Button pushed function: START_FLAPPING
        function START_FLAPPINGPushed(app, event)
            if(app.bat_connected == true)
                if(app.flapping == false)
                    write(app.cmdID, 1)
                    write(app.cmdVALUE,app.FREQUENCYSlider.Value)
                    app.START_FLAPPING.Text = "STOP";
                    app.flapping = true;
                else
                    write(app.cmdID, 1)
                    write(app.cmdVALUE,0)
                    app.START_FLAPPING.Text = "START FLAPPING";
                    app.flapping = false;
                end
            end
        end

        % Value changed function: FREQUENCYSlider
        function FREQUENCYSliderValueChanged(app, event)
            value = app.FREQUENCYSlider.Value;
            [~, minIdx] = min(abs(value - event.Source.MajorTicks(:)));
            event.Source.Value = event.Source.MajorTicks(minIdx);
            value = event.Source.MajorTicks(minIdx); 

            if(app.bat_connected == true && app.flapping == true)
                write(app.cmdID, 1);
                write(app.cmdVALUE,value);
            end
        end

        % Value changed function: MANUAL_Slider
        function MANUAL_SliderValueChanged(app, event)
            value = app.MANUAL_Slider.Value;
            [~, minIdx] = min(abs(value - event.Source.MinorTicks(:)));
            event.Source.Value = event.Source.MinorTicks(minIdx);
            value = event.Source.MinorTicks(minIdx); 

            if(app.bat_connected == true)
                    write(app.cmdMANUAL,value+100);
            end
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.MANUAL_Slider.Value = 0;
            if(app.bat_connected == true)
                 write(app.cmdMANUAL, 100);
            end
        end

        % Button pushed function: TareButton
        function TareButtonPushed(app, event)
            if(app.lcSerial_connected)
                flush(app.loadcell);
                writeline(app.loadcell,num2str(1)); 
                CalibBuffer(1) =  0;
                for i = 1:80
                    CalibBuffer(i) = str2double(readline(app.loadcell));
                end
                app.offset = mean(CalibBuffer(2:end));
                flush(app.loadcell);
                app.OffsetEditField.Value = app.offset;
                assignin('base','offset',app.offset);
            end
        end

        % Button pushed function: STARTREADINGButton
        function STARTREADINGButtonPushed(app, event)
            if(app.lcSerial_connected) 
                flush(app.loadcell);
                writeline(app.loadcell,"1"); 
                app.STARTREADINGButton.Text = "Collecting Data...";
                app.STARTREADINGButton.Enable = "off";
                writeline(app.loadcell,num2str(app.TempoSpinner.Value)); 
                for i = 1:app.TempoSpinner.Value*80
                    DataBuffer(i) = str2double(readline(app.loadcell));
                end
                DataBuffer = (DataBuffer - app.offset)/10; 
                TimeBuffer = 0:app.TempoSpinner.Value/length(DataBuffer):app.TempoSpinner.Value;
                TimeBuffer = TimeBuffer(2:end);
                app.UIAxes.XLim = [0,TimeBuffer(end)];
                app.UIAxes.YLim = [-max(DataBuffer)-2,max(DataBuffer)+2];
                plot(app.UIAxes,TimeBuffer,DataBuffer,'Color',[0 0 0],'LineWidth',2);
                assignin('base','time',TimeBuffer);
                assignin('base','force',DataBuffer);
                app.STARTREADINGButton.Text = "START READING";
                app.STARTREADINGButton.Enable = "on";
            end
        end

        % Button pushed function: APPLY_A
        function APPLY_APushed(app, event)
            if(app.bat_connected == true)
                write(app.cmdID, 2);
                write(app.cmdVALUE,app.DUTYCYCLE_A_Slider.Value);
            end 
        end

        % Button pushed function: APPLY_B
        function APPLY_BPushed(app, event)
            if(app.bat_connected == true)
                write(app.cmdID, 3);
                write(app.cmdVALUE,app.DUTYCYCLE_B_Slider.Value);
            end 
        end

        % Button pushed function: APPLY_R_A
        function APPLY_R_AButtonPushed(app, event)
             if(app.bat_connected == true)
                write(app.cmdID, 4);
                write(app.cmdVALUE,app.POWER_RA_Slider.Value);
            end 
        end

        % Button pushed function: APPLY_R_B
        function APPLY_R_BButtonPushed(app, event)
             if(app.bat_connected == true)
                write(app.cmdID, 5);
                write(app.cmdVALUE,app.POWER_RB_Slider.Value);
            end 
        end

        % Button pushed function: APPLY_L_A
        function APPLY_L_AButtonPushed(app, event)
             if(app.bat_connected == true)
                write(app.cmdID, 6);
                write(app.cmdVALUE,app.POWER_LA_Slider.Value);
            end 
        end

        % Button pushed function: APPLY_L_B
        function APPLY_L_BButtonPushed(app, event)
             if(app.bat_connected == true)
                write(app.cmdID, 7);
                write(app.cmdVALUE,app.POWER_LB_Slider.Value);
            end 
        end

        % Button pushed function: ROLLCONTROLONButton
        function ROLLCONTROLONButtonPushed(app, event)
            if(app.bat_connected == true)
                write(app.cmdID, 10);
                write(app.cmdVALUE,1);
            end
        end

        % Button pushed function: ROLLCONTROLOFFButton
        function ROLLCONTROLOFFButtonPushed(app, event)
            if(app.bat_connected == true)
                write(app.cmdID, 10);
                write(app.cmdVALUE,0);
            end
        end

        % Value changed function: POWER_LA_Slider
        function POWER_LA_SliderValueChanged(app, event)
            value = app.POWER_LA_Slider.Value;
            [~, minIdx] = min(abs(value - event.Source.MinorTicks(:)));
            event.Source.Value = event.Source.MinorTicks(minIdx);
            value = event.Source.MinorTicks(minIdx); 
            app.POWERRIGHTWLabel.Text = "POWER LEFT: " + num2str(value) + "/255";
        end

        % Value changed function: POWER_RA_Slider
        function POWER_RA_SliderValueChanged(app, event)
            value = app.POWER_RA_Slider.Value;
            [~, minIdx] = min(abs(value - event.Source.MinorTicks(:)));
            event.Source.Value = event.Source.MinorTicks(minIdx);
            value = event.Source.MinorTicks(minIdx); 
            app.POWERRIGHT200255Label.Text = "POWER RIGHT: " + num2str(value) + "/255";            
        end

        % Value changed function: POWER_LB_Slider
        function POWER_LB_SliderValueChanged(app, event)
            value = app.POWER_LB_Slider.Value;
            [~, minIdx] = min(abs(value - event.Source.MinorTicks(:)));
            event.Source.Value = event.Source.MinorTicks(minIdx);
            value = event.Source.MinorTicks(minIdx); 
            app.POWERLEFTWLabel.Text = "POWER LEFT: " + num2str(value) + "/255";            
        end

        % Value changed function: POWER_RB_Slider
        function POWER_RB_SliderValueChanged(app, event)
            value = app.POWER_RB_Slider.Value;
            [~, minIdx] = min(abs(value - event.Source.MinorTicks(:)));
            event.Source.Value = event.Source.MinorTicks(minIdx);
            value = event.Source.MinorTicks(minIdx); 
            app.POWERLEFTWLabel_2.Text = "POWER RIGHT: " + num2str(value) + "/255";   
        end

        % Value changed function: DUTYCYCLE_A_Slider
        function DUTYCYCLE_A_SliderValueChanged(app, event)
            value = app.DUTYCYCLE_A_Slider.Value;
            [~, minIdx] = min(abs(value - event.Source.MinorTicks(:)));
            event.Source.Value = event.Source.MinorTicks(minIdx);
            value = event.Source.MinorTicks(minIdx); 
            app.DUTYCYCLE25Label_3.Text = "DUTY CYCLE: " + num2str(value) + "%";                
        end

        % Value changed function: DUTYCYCLE_B_Slider
        function DUTYCYCLE_B_SliderValueChanged(app, event)
            value = app.DUTYCYCLE_B_Slider.Value;
            [~, minIdx] = min(abs(value - event.Source.MinorTicks(:)));
            event.Source.Value = event.Source.MinorTicks(minIdx);
            value = event.Source.MinorTicks(minIdx); 
            app.DUTYCYCLE25Label_4.Text = "DUTY CYCLE: " + num2str(value) + "%";                
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CONNECT_Button = uibutton(app.UIFigure, 'push');
            app.CONNECT_Button.ButtonPushedFcn = createCallbackFcn(app, @CONNECT_ButtonPushed, true);
            app.CONNECT_Button.Text = 'CONNECT TO BAT';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BATCONTROLTab = uitab(app.TabGroup);
            app.BATCONTROLTab.Title = 'BAT CONTROL';
            app.FREQUENCYSliderLabel = uilabel(app.BATCONTROLTab);
            app.FREQUENCYSliderLabel.Text = 'FREQUENCY';
            app.FREQUENCYSlider = uislider(app.BATCONTROLTab);
            app.FREQUENCYSlider.Limits = [1 10];
            app.FREQUENCYSlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.FREQUENCYSlider.ValueChangedFcn = createCallbackFcn(app, @FREQUENCYSliderValueChanged, true);
            app.FREQUENCYSlider.MinorTicks = [];
            app.FREQUENCYSlider.Value = 5;
            app.START_FLAPPING = uibutton(app.BATCONTROLTab, 'push');
            app.START_FLAPPING.ButtonPushedFcn = createCallbackFcn(app, @START_FLAPPINGPushed, true);
            app.START_FLAPPING.Text = 'START FLAPPING';
            app.MANUAL_Slider = uislider(app.BATCONTROLTab);
            app.MANUAL_Slider.Limits = [-100 100];
            app.MANUAL_Slider.MajorTicks = [-250 -225 -200 -175 -150 -125 -100 -75 -50 -25 0 25 50 75 100 125 150 175 200 225 250];
            app.MANUAL_Slider.ValueChangedFcn = createCallbackFcn(app, @MANUAL_SliderValueChanged, true);
            app.MANUAL_Slider.MinorTicks = [-250 -249 -248 -247 -246 -245 -244 -243 -242 -241 -240 -239 -238 -237 -236 -235 -234 -233 -232 -231 -230 -229 -228 -227 -226 -225 -224 -223 -222 -221];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
            app.RESETButton = uibutton(app.BATCONTROLTab, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.LEFTLabel = uilabel(app.BATCONTROLTab);
            app.LEFTLabel.Text = 'LEFT';
            app.RIGHTLabel = uilabel(app.BATCONTROLTab);
            app.RIGHTLabel.Text = 'RIGHT';
            app.DIRECTIONLabel = uilabel(app.BATCONTROLTab);
            app.DIRECTIONLabel.Text = 'DIRECTION';
            app.PITCHGaugeLabel = uilabel(app.BATCONTROLTab);
            app.PITCHGaugeLabel.Text = 'PITCH';
            app.PITCHGauge = uigauge(app.BATCONTROLTab, 'linear');
            app.PITCHGauge.Limits = [-60 60];
            app.PITCHGauge.MajorTicks = [-60 -45 -30 -15 0 15 30 45 60];
            app.ROLLGaugeLabel = uilabel(app.BATCONTROLTab);
            app.ROLLGaugeLabel.Text = 'ROLL';
            app.ROLLGauge = uigauge(app.BATCONTROLTab, 'semicircular');
            app.ROLLGauge.Limits = [-90 90];
            app.ROLLCONTROLONButton = uibutton(app.BATCONTROLTab, 'push');
            app.ROLLCONTROLONButton.ButtonPushedFcn = createCallbackFcn(app, @ROLLCONTROLONButtonPushed, true);
            app.ROLLCONTROLONButton.Text = 'ROLL CONTROL ON  ';
            app.ROLLCONTROLOFFButton = uibutton(app.BATCONTROLTab, 'push');
            app.ROLLCONTROLOFFButton.ButtonPushedFcn = createCallbackFcn(app, @ROLLCONTROLOFFButtonPushed, true);
            app.ROLLCONTROLOFFButton.Text = 'ROLL CONTROL OFF';
            app.BATSETTINGSTab = uitab(app.TabGroup);
            app.BATSETTINGSTab.Title = 'BAT SETTINGS';
            app.AGONISTPanel = uipanel(app.BATSETTINGSTab);
            app.AGONISTPanel.Title = 'AGONIST';
            app.POWERRIGHTWLabel = uilabel(app.AGONISTPanel);
            app.POWERRIGHTWLabel.Text = 'POWER LEFT: 200/255';
            app.POWER_LA_Slider = uislider(app.AGONISTPanel);
            app.POWER_LA_Slider.Limits = [0 255];
            app.POWER_LA_Slider.MajorTicks = [0 50 100 150 200 255];
            app.POWER_LA_Slider.ValueChangedFcn = createCallbackFcn(app, @POWER_LA_SliderValueChanged, true);
            app.POWER_LA_Slider.MinorTicks = [0 25 50 75 100 125 150 175 200 225 255];
            app.POWER_LA_Slider.Tooltip = {''};
            app.POWER_LA_Slider.Value = 200;
            app.POWERRIGHT200255Label = uilabel(app.AGONISTPanel);
            app.POWERRIGHT200255Label.Text = 'POWER RIGHT: 200/255';
            app.POWER_RA_Slider = uislider(app.AGONISTPanel);
            app.POWER_RA_Slider.Limits = [0 255];
            app.POWER_RA_Slider.MajorTicks = [0 50 100 150 200 255];
            app.POWER_RA_Slider.ValueChangedFcn = createCallbackFcn(app, @POWER_RA_SliderValueChanged, true);
            app.POWER_RA_Slider.MinorTicks = [0 25 50 75 100 125 150 175 200 225 255];
            app.POWER_RA_Slider.Value = 200;
            app.DUTYCYCLE25Label_3 = uilabel(app.AGONISTPanel);
            app.DUTYCYCLE25Label_3.Text = 'DUTY CYCLE: 25%';
            app.DUTYCYCLE_A_Slider = uislider(app.AGONISTPanel);
            app.DUTYCYCLE_A_Slider.Limits = [0 50];
            app.DUTYCYCLE_A_Slider.MajorTicks = [0 10 20 30 40 50];
            app.DUTYCYCLE_A_Slider.MajorTickLabels = {'0', '10', '20', '30', '40', '50'};
            app.DUTYCYCLE_A_Slider.ValueChangedFcn = createCallbackFcn(app, @DUTYCYCLE_A_SliderValueChanged, true);
            app.DUTYCYCLE_A_Slider.MinorTicks = [0 5 10 15 20 25 30 35 40 45];
            app.DUTYCYCLE_A_Slider.Value = 26;
            app.APPLY_A = uibutton(app.AGONISTPanel, 'push');
            app.APPLY_A.ButtonPushedFcn = createCallbackFcn(app, @APPLY_APushed, true);
            app.APPLY_A.Text = 'APPLY';
            app.APPLY_L_A = uibutton(app.AGONISTPanel, 'push');
            app.APPLY_L_A.ButtonPushedFcn = createCallbackFcn(app, @APPLY_L_AButtonPushed, true);
            app.APPLY_L_A.Text = 'APPLY';
            app.APPLY_R_A = uibutton(app.AGONISTPanel, 'push');
            app.APPLY_R_A.ButtonPushedFcn = createCallbackFcn(app, @APPLY_R_AButtonPushed, true);
            app.APPLY_R_A.Text = 'APPLY';
            app.ANTAGONISTPanel = uipanel(app.BATSETTINGSTab);
            app.ANTAGONISTPanel.Title = 'ANTAGONIST';
            app.POWERLEFTWLabel = uilabel(app.ANTAGONISTPanel);
            app.POWERLEFTWLabel.Text = 'POWER LEFT: 200/255';
            app.POWER_LB_Slider = uislider(app.ANTAGONISTPanel);
            app.POWER_LB_Slider.Limits = [0 255];
            app.POWER_LB_Slider.MajorTicks = [0 50 100 150 200 255];
            app.POWER_LB_Slider.ValueChangedFcn = createCallbackFcn(app, @POWER_LB_SliderValueChanged, true);
            app.POWER_LB_Slider.MinorTicks = [0 25 50 75 100 125 150 175 200 225 255];
            app.POWER_LB_Slider.Value = 200;
            app.APPLY_B = uibutton(app.ANTAGONISTPanel, 'push');
            app.APPLY_B.ButtonPushedFcn = createCallbackFcn(app, @APPLY_BPushed, true);
            app.APPLY_B.Text = 'APPLY';
            app.POWERLEFTWLabel_2 = uilabel(app.ANTAGONISTPanel);
            app.POWERLEFTWLabel_2.Text = 'POWER RIGHT: 200/255';
            app.POWER_RB_Slider = uislider(app.ANTAGONISTPanel);
            app.POWER_RB_Slider.Limits = [0 255];
            app.POWER_RB_Slider.MajorTicks = [0 50 100 150 200 255];
            app.POWER_RB_Slider.ValueChangedFcn = createCallbackFcn(app, @POWER_RB_SliderValueChanged, true);
            app.POWER_RB_Slider.MinorTicks = [0 25 50 75 100 125 150 175 200 225 255];
            app.POWER_RB_Slider.Value = 200;
            app.DUTYCYCLE25Label_4 = uilabel(app.ANTAGONISTPanel);
            app.DUTYCYCLE25Label_4.Text = 'DUTY CYCLE 25%';
            app.DUTYCYCLE_B_Slider = uislider(app.ANTAGONISTPanel);
            app.DUTYCYCLE_B_Slider.Limits = [0 50];
            app.DUTYCYCLE_B_Slider.MajorTicks = [0 10 20 30 40 50];
            app.DUTYCYCLE_B_Slider.MajorTickLabels = {'0', '10', '20', '30', '40', '50'};
            app.DUTYCYCLE_B_Slider.ValueChangedFcn = createCallbackFcn(app, @DUTYCYCLE_B_SliderValueChanged, true);
            app.DUTYCYCLE_B_Slider.MinorTicks = [0 5 10 15 20 25 30 35 40 45];
            app.DUTYCYCLE_B_Slider.Value = 26;
            app.APPLY_L_B = uibutton(app.ANTAGONISTPanel, 'push');
            app.APPLY_L_B.ButtonPushedFcn = createCallbackFcn(app, @APPLY_L_BButtonPushed, true);
            app.APPLY_L_B.Text = 'APPLY';
            app.APPLY_R_B = uibutton(app.ANTAGONISTPanel, 'push');
            app.APPLY_R_B.ButtonPushedFcn = createCallbackFcn(app, @APPLY_R_BButtonPushed, true);
            app.APPLY_R_B.Text = 'APPLY';
            app.FORCESENSORTab = uitab(app.TabGroup);
            app.FORCESENSORTab.Title = 'FORCE SENSOR';
            app.UIAxes = uiaxes(app.FORCESENSORTab);
            title(app.UIAxes, 'Force Readings')
            xlabel(app.UIAxes, 'time [s]')
            ylabel(app.UIAxes, 'force [g]')
            zlabel(app.UIAxes, 'Z')
            app.CONNECTBTLoadCell_Button = uibutton(app.FORCESENSORTab, 'push');
            app.CONNECTBTLoadCell_Button.ButtonPushedFcn = createCallbackFcn(app, @CONNECTBTLoadCell_ButtonPushed, true);
            app.CONNECTBTLoadCell_Button.Text = 'Connect to Bluetooth';
            app.SerialPortDropDownLabel = uilabel(app.FORCESENSORTab);
            app.SerialPortDropDownLabel.Text = 'Serial Port:';
            app.SerialPort_LoadCell = uidropdown(app.FORCESENSORTab);
            app.SerialPort_LoadCell.Items = {'COM1'};
            app.SerialPort_LoadCell.Value = 'COM1';
            app.ConnectSERIALLoadCell_Button = uibutton(app.FORCESENSORTab, 'push');
            app.ConnectSERIALLoadCell_Button.ButtonPushedFcn = createCallbackFcn(app, @ConnectSERIALLoadCell_ButtonPushed, true);
            app.ConnectSERIALLoadCell_Button.Text = 'Connect to Serial';
            app.TareButton = uibutton(app.FORCESENSORTab, 'push');
            app.TareButton.ButtonPushedFcn = createCallbackFcn(app, @TareButtonPushed, true);
            app.TareButton.Text = 'Tare';
            app.OffsetEditFieldLabel = uilabel(app.FORCESENSORTab);
            app.OffsetEditFieldLabel.Text = 'Offset:';
            app.OffsetEditField = uieditfield(app.FORCESENSORTab, 'numeric');
            app.dgLabel = uilabel(app.FORCESENSORTab);
            app.dgLabel.Text = 'dg';
            app.TempoSpinnerLabel = uilabel(app.FORCESENSORTab);
            app.TempoSpinnerLabel.Text = 'Tempo:';
            app.TempoSpinner = uispinner(app.FORCESENSORTab);
            app.STARTREADINGButton = uibutton(app.FORCESENSORTab, 'push');
            app.STARTREADINGButton.ButtonPushedFcn = createCallbackFcn(app, @STARTREADINGButtonPushed, true);
            app.STARTREADINGButton.Text = 'START READING';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Control

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end